classdef createSampleFile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SampleshiftEditField       matlab.ui.control.NumericEditField
        SampleshiftEditFieldLabel  matlab.ui.control.Label
        SelectfileButton           matlab.ui.control.Button
        LengthofmeasurementinminEditField  matlab.ui.control.NumericEditField
        LengthofmeasurementinminEditFieldLabel  matlab.ui.control.Label
        SampleSizeEditField        matlab.ui.control.NumericEditField
        SampleSizeEditFieldLabel   matlab.ui.control.Label
        OKButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingapp % mainapp
        filename
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
          % Store main app object
          app.callingapp = mainapp;

        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            importdata(app.callingapp,app.SampleSizeEditField.Value,app.LengthofmeasurementinminEditField.Value,app.SampleshiftEditField.Value,app.filename);
            
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            % Delete the dialog box 
            delete(app)            
        end

        % Value changed function: SampleSizeEditField
        function SampleSizeEditFieldValueChanged(app, event)
            value = app.SampleSizeEditField.Value;
            
        end

        % Value changed function: LengthofmeasurementinminEditField
        function LengthofmeasurementinminEditFieldValueChanged(app, event)
            value = app.LengthofmeasurementinminEditField.Value;
            
        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            [file, path] = uigetfile('*.EDF');
            app.filename = file;
        end

        % Value changed function: SampleshiftEditField
        function SampleshiftEditFieldValueChanged(app, event)
            value = app.SampleshiftEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.SampleSizeEditFieldLabel = uilabel(app.UIFigure);
            app.SampleSizeEditFieldLabel.Text = 'Sample Size';
            app.SampleSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SampleSizeEditField.ValueChangedFcn = createCallbackFcn(app, @SampleSizeEditFieldValueChanged, true);
            app.LengthofmeasurementinminEditFieldLabel = uilabel(app.UIFigure);
            app.LengthofmeasurementinminEditFieldLabel.Text = 'Length of measurement (in min)';
            app.LengthofmeasurementinminEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthofmeasurementinminEditField.ValueChangedFcn = createCallbackFcn(app, @LengthofmeasurementinminEditFieldValueChanged, true);
            app.SelectfileButton = uibutton(app.UIFigure, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.SampleshiftEditFieldLabel = uilabel(app.UIFigure);
            app.SampleshiftEditFieldLabel.Text = 'Sample shift ';
            app.SampleshiftEditField = uieditfield(app.UIFigure, 'numeric');
            app.SampleshiftEditField.ValueChangedFcn = createCallbackFcn(app, @SampleshiftEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = createSampleFile(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end