classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        createfileButton              matlab.ui.control.Button
        loadmeasurementfirstLabel     matlab.ui.control.Label
        CurrentmeasurementLabel       matlab.ui.control.Label
        SelectHRVvariableButtonGroup  matlab.ui.container.ButtonGroup
        DFAalpha1Button               matlab.ui.control.RadioButton
        LFHFPowerFFTButton            matlab.ui.control.RadioButton
        StressindexButton             matlab.ui.control.RadioButton
        RMSSDButton                   matlab.ui.control.RadioButton
        LoadmeasurementButton         matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        Toolbar                       matlab.ui.container.Toolbar
        PushTool                      matlab.ui.container.toolbar.PushTool
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        data % the mat-file that has been loaded
        setup % the setup mat-file
        createSampleFile % Description
    end
    
    methods (Access = public)
        
        function importdata(app,sampleLength,fileLength,sampleShift,filename)
             createcsv(app,sampleLength,fileLength,sampleShift,filename)
        end
        
        function createcsv(app,sampleLength,fileLength,sampleShift,filename)
            
            %min +1 -sample 
            color = " # 0 255 0";
            csvstr = filename + "," + "1" + ",";
            
            starttime = 0;
            endtime = sampleLength*60;
            
            for i =1:(fileLength + 1 - sampleLength)
                
                csvstr = strcat(csvstr,"Sample",int2str(i),color,",",int2str(starttime),",",int2str(endtime),",");
                endtime = endtime + (sampleShift*60);
                starttime = starttime + (sampleShift*60); 
            end
           
            writematrix(csvstr, "Kubios_Samples.csv");
     
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function setupApp(app)
        
              app.setup = setupHRVvariables();
              
%% TODO: add buttons dynamically, so its easier to add HRV variables
%             vars = app.setup.result.short;
%             buttonGrp = app.SelectHRVvariableButtonGroup;
% 
%             for idx = 1:length(vars)
%                 % TODO: fix the relative pos
%                 ypos = buttonGrp.Position(2) + idx * 15;
%                 rb = uiradiobutton(buttonGrp, 'Position', [5 ypos 123 316]);
%                 rb.Text = vars(idx);
%             end
        end

        % Button pushed function: LoadmeasurementButton
        function selectFile(app, event)
            % deactivate buttons while loading file
            radioButtons = get(app.SelectHRVvariableButtonGroup, 'Children');
            set(radioButtons, 'Enable', 'off');

            fig = app.UIFigure;
            msg = ['Do you want to load an export with or without ...' ...
                ' manually configured samples?'];
            title = 'Choose file configuration';
            selection = uiconfirm(fig,msg,title, ...
           'Options',{'With samples','Without samples','Cancel'}, ...
           'DefaultOption',2,'CancelOption',3);
            
            if ~strcmp(selection, 'Cancel')
                [file, path] = uigetfile('*.mat');
                path2file = fullfile(path, file);
                if strcmp(selection, 'Without samples')
                    app.data = WithoutSamplesParser(path2file);
                else
                    app.data = WithSamplesParser(path2file);
                end
            end

            app.selectHRVvariable();

            % enable radio buttons after the file has been load
            set(radioButtons, 'Enable', 'on');
            % show name of selected measurement file
            set(app.loadmeasurementfirstLabel, 'Text', file);
        end

        % Selection changed function: SelectHRVvariableButtonGroup
        function selectHRVvariable(app, event)
            selectedButton = app.SelectHRVvariableButtonGroup.SelectedObject;
            selectedVar = selectedButton.Text;
            
            app.data.plotToAxes(app.setup, app.UIAxes, selectedVar);
        end

        % Close request function: UIFigure
        function checkIfSaved(app, event)
            fig = app.UIFigure;
            msg = ['Did you save your evaluation? Otherwise it will' ...
                ' not be saved.'];
            title = 'Evaluation saved?';
            selection = uiconfirm(fig,msg,title, ...
           'Options',{'Yes','No'}, 'DefaultOption',2,'CancelOption',2);
            
            if strcmp(selection, 'Yes')
                delete(app)
            end
        end

        % Button pushed function: createfileButton
        function createfileButtonPushed(app, event)
            app.createSampleFile = createSampleFile(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {447, 447};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @checkIfSaved, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadmeasurementButton = uibutton(app.LeftPanel, 'push');
            app.LoadmeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @selectFile, true);
            app.LoadmeasurementButton.Text = 'Load measurement';
            app.SelectHRVvariableButtonGroup = uibuttongroup(app.LeftPanel);
            app.SelectHRVvariableButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @selectHRVvariable, true);
            app.SelectHRVvariableButtonGroup.Title = 'Select HRV variable';
            app.RMSSDButton = uiradiobutton(app.SelectHRVvariableButtonGroup);
            app.RMSSDButton.Enable = 'off';
            app.RMSSDButton.Text = 'RMSSD';
            app.RMSSDButton.Value = true;
            app.StressindexButton = uiradiobutton(app.SelectHRVvariableButtonGroup);
            app.StressindexButton.Enable = 'off';
            app.StressindexButton.Text = 'Stress index';
            app.LFHFPowerFFTButton = uiradiobutton(app.SelectHRVvariableButtonGroup);
            app.LFHFPowerFFTButton.Enable = 'off';
            app.LFHFPowerFFTButton.Text = 'LF/HF Power FFT';
            app.DFAalpha1Button = uiradiobutton(app.SelectHRVvariableButtonGroup);
            app.DFAalpha1Button.Enable = 'off';
            app.DFAalpha1Button.Text = 'DFA alpha1';
            app.CurrentmeasurementLabel = uilabel(app.LeftPanel);
            app.CurrentmeasurementLabel.Text = 'Current measurement:';
            app.loadmeasurementfirstLabel = uilabel(app.LeftPanel);
            app.loadmeasurementfirstLabel.Text = 'load measurement first';
            app.createfileButton = uibutton(app.LeftPanel, 'push');
            app.createfileButton.ButtonPushedFcn = createCallbackFcn(app, @createfileButtonPushed, true);
            app.createfileButton.Text = 'create file';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @setupApp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end